classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        WritetomidiButton  matlab.ui.control.Button
        PlayButton         matlab.ui.control.Button
        AmadeusLabel       matlab.ui.control.Label
        WritetotxtButton   matlab.ui.control.Button
        ImportButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        DataFileName % Path to data file
        midiData % Data from Midi files
        txtFileName % File to write midi data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Показать диалоговое окно выбора файла
            [filename, path] = uigetfile('*.midi;*.mid', 'Выберите MIDI-файл');

            % Проверить, был ли выбран файл
            if isequal(filename, 0)
                disp('Выбор файла отменен');
            else
                % Полный путь к выбранному файлу
                fullPath = fullfile(path, filename);
                app.DataFileName = fullPath;
                % Далее выполните необходимые действия с выбранным файлом
                % Например, загрузите данные и выполните ваши операции
                disp(['Выбран MIDI-файл: ', fullPath]);
            end
        end

        % Button pushed function: WritetotxtButton
        function WritetotxtButtonPushed(app, event)
            addpath(genpath('D:\Develop\Amadeus\matlab-midi-master\src'))
            midiFile = readmidi(app.DataFileName);
            app.midiData = midiInfo(midiFile);
            app.txtFileName = 'output.txt';
            writematrix(app.midiData, app.txtFileName);
        end

        % Button pushed function: WritetomidiButton
        function WritetomidiButtonPushed(app, event)
            matrixData = load(app.txtFileName);
            midi_new = matrix2midi(matrixData);
            writemidi(midi_new, 'testout.mid');
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            [numRow, numCol] = size(app.midiData);
            time = (app.midiData(numRow, 5))+1;
            size_arr = (int32(44100*time));
            wave_arr = zeros(1, size_arr);

            for i = 1:length(app.midiData)
                duration = app.midiData(i,6);
                note = app.midiData(i,3);
                amplitude = app.midiData(i,4) / 127;
                constant_exp = -0.0015;
                n = 6;
                T = (0:1/44100:duration);
                wave = zeros(size(T));
                freq = 440 * 2.^((note-73)/12);
                number_sin = 2 * pi * freq;
                number_exp = constant_exp * number_sin;
                for j = 1:n
                    wave = wave + amplitude * sin(j * number_sin * T) .* exp(number_exp * T) ./ 2^(j-1);
                end 
                wave = (wave + wave.^(4))/n;
                startIndex = int32(44100 * app.midiData(i,5)) + 1;
                wave_arr(startIndex:startIndex + length(wave) - 1) =+ wave;
            end
                maxValue = max(abs(wave_arr));
                soundsc(wave_arr/maxValue, 44100);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.WritetotxtButton = uibutton(app.UIFigure, 'push');
            app.WritetotxtButton.ButtonPushedFcn = createCallbackFcn(app, @WritetotxtButtonPushed, true);
            app.WritetotxtButton.Text = 'Write to txt';
            app.AmadeusLabel = uilabel(app.UIFigure);
            app.AmadeusLabel.Text = 'Amadeus';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.WritetomidiButton = uibutton(app.UIFigure, 'push');
            app.WritetomidiButton.ButtonPushedFcn = createCallbackFcn(app, @WritetomidiButtonPushed, true);
            app.WritetomidiButton.Text = 'Write to midi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end